classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LowerSlider             matlab.ui.control.Slider
        MidSliderLabel          matlab.ui.control.Label
        LowerSliderLabel        matlab.ui.control.Label
        UpperSliderLabel        matlab.ui.control.Label
        ColorLabel              matlab.ui.control.Label
        UpperSlider             matlab.ui.control.Slider
        ColorDropDown           matlab.ui.control.DropDown
        LowerLabel              matlab.ui.control.Label
        UpperLabel              matlab.ui.control.Label
        MyShapeDrawingAppLabel  matlab.ui.control.Label
        DrawButton              matlab.ui.control.Button
        ShapeDropDown           matlab.ui.control.DropDown
        SelectyourShapeLabel    matlab.ui.control.Label
        Axes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function drawCircle(app, radius,fillColor)
        % Clear the axes to remove any previous shapes
         cla(app.Axes);
        
        % Draw a circle with the given radius
       pos = [-radius, -radius, 2*radius, 2*radius];
       rectangle(app.Axes,'Position',pos, 'Curvature', [1, 1], 'FaceColor', fillColor);
        axis(app.Axes, 'equal');  % Set aspect ratio to be equal
        title(app.Axes,'Circle');
        checkColor = app.ColorDropDown.Value;
        if strcmpi(checkColor, 'Baby Blue')
            xlabel(app.Axes,['Radius: ', num2str(round(radius,2)), newline, 'Color: Baby Blue'] )
        else
            xlabel(app.Axes,['Radius: ', num2str(round(radius,2)), newline, 'Color: ', fillColor] )
        end
    end
        
    function drawEquilateralTriangle (app,sideLength,fillColor)        
         cla(app.Axes);
         x = [0, sideLength, sideLength / 2, 0];
         y = [0, 0, sideLength * sqrt(3) / 2, 0];
         fill(app.Axes, x, y, fillColor);   
         axis(app.Axes, 'equal');
         title(app.Axes,'Equilateral Triangle');
         checkColor = app.ColorDropDown.Value;
         if strcmpi(checkColor, 'Baby Blue')
             xlabel(app.Axes,['Side Length: ', num2str(round(sideLength,2)), newline, 'Color: Baby Blue'] )
         else
             xlabel(app.Axes,['Side Length: ', num2str(round(sideLength,2)), newline, 'Color: ', fillColor] )
         end
    end

    function drawRectangle(app,length,width,fillColor)
            cla(app.Axes);
            shapeSelected = app.ShapeDropDown.Value;
            rectangle(app.Axes,'Position', [0, 0, length, width], 'EdgeColor', fillColor, 'FaceColor', fillColor);  
            if strcmp(shapeSelected, 'Rectangle')            
            %setting the axis to display obj further from the scale
            axis (app.Axes,[-1 length + 3 -1 width + 3])
            title(app.Axes,'Rectangle');
            checkColor = app.ColorDropDown.Value;
            if strcmpi(checkColor, 'Baby Blue')
                xlabel(app.Axes,['Length: ', num2str(round(length,2)), newline,'Width: ', num2str(round(width,2)), newline 'Color: Baby Blue'])
            else
                xlabel(app.Axes,['Length: ', num2str(round(length,2)), newline,'Width: ', num2str(round(width,2)), newline 'Color: ', fillColor])
            end           
            else
            axis(app.Axes, 'equal');
            title(app.Axes,'Square');
            checkColor = app.ColorDropDown.Value;
            if strcmpi(checkColor, 'Baby Blue')
                 xlabel(app.Axes,['Side Length: ', num2str(round(length,2)), newline,'Color: Baby Blue'])
            else
                 xlabel(app.Axes,['Side Length: ', num2str(round(length,2)), newline,'Color: ', fillColor])
            end
           
            end    
    end

    function drawTriangle(app,base,height,fillColor)
           cla(app.Axes);
         x = [0,base,base/2];
         y = [0,0,height];
         fill(app.Axes,x, y,fillColor); 
         axis(app.Axes,[-2 base+1 -2 height+1]) 
         title(app.Axes,'Triangle');
         checkColor = app.ColorDropDown.Value;
         if strcmpi(checkColor, 'Baby Blue')
             xlabel(app.Axes,['Base: ', num2str(round(base,2)), newline,'Height: ', num2str(round(height,2)), newline, 'Color: Baby Blue']);
         else
            xlabel(app.Axes,['Base: ', num2str(round(base,2)), newline,'Height: ', num2str(round(height,2)), newline, 'Color: ', fillColor]);
        end
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xlabel(app.Axes,'');
            title(app.Axes,'');            
            %Hide component
            app.LowerLabel.Visible = 'off'; 
            app.UpperLabel.Visible = 'off';         
            app.MidSliderLabel.Visible ='off'; 
            app.LowerSliderLabel.Visible = 'off';
            app.UpperSliderLabel.Visible='off';            
            app.ColorLabel.Visible ='off';            
            app.LowerSlider.Visible = 'off';
            app.ColorDropDown.Visible = 'off';            
            app.UpperSlider.Visible ='off';                    
            app.UpperLabel.Visible='off';
            app.UpperLabel.Text = '';           
            app.Axes.reset;
        end

        % Value changed function: ShapeDropDown
        function DropDownValueChanged(app, event)
            xlabel(app.Axes,'');
            title(app.Axes,'');
            value = app.ShapeDropDown.Value;            
            %Hide component
            app.LowerLabel.Visible = 'off'; 
            app.UpperLabel.Visible = 'off';         
            app.MidSliderLabel.Visible ='off'; 
            app.LowerSliderLabel.Visible = 'off';
            app.UpperSliderLabel.Visible='off';            
            app.ColorLabel.Visible ='off';            
            app.LowerSlider.Visible = 'off';
            app.ColorDropDown.Visible = 'off';            
            app.UpperSlider.Visible ='off';                    
            app.UpperLabel.Visible='off';
            app.UpperLabel.Text = '';
            app.Axes.reset;
            if strcmp(value, 'Circle')
            cla(app.Axes);
            app.UpperSliderLabel.Text = '';
            app.UpperSliderLabel.Visible='on';            
            app.UpperSlider.Visible = 'on';
            app.UpperLabel.Visible='on';
            app.UpperLabel.Text = 'Circle Radius:';
            app.UpperSlider.Value = 0;
            app.ColorDropDown.Visible = 'on';
            app.ColorDropDown.Value = 'Select A Color';
            app.ColorLabel.Visible = 'on';
            app.UpperLabel.Text = 'Circle Radius:';
            app.ColorLabel.Position =[14,199,104,30];
            app.ColorDropDown.Position =[200,198,138,31];
            app.DrawButton.Position=[154,151,100,38];
            elseif strcmp(value,'Rectangle')
            cla(app.Axes);
            app.UpperSliderLabel.Text = '';
            app.UpperSlider.Value = 0; 
            app.LowerSliderLabel.Text = '';
            app.LowerSliderLabel.Visible = 'on';
            app.UpperSliderLabel.Visible='on';
            app.UpperSlider.Visible = 'on';
            app.UpperLabel.Visible = 'on';
            app.UpperLabel.Text = 'Rectangle Width:';
            app.ColorLabel.Visible ='on';
            app.LowerLabel.Visible ='on';
            app.LowerLabel.Text ='Rectangle Length:';
            app.LowerSlider.Visible ='on';
            app.LowerSlider.Value = 0;
            app.ColorDropDown.Visible = 'on';
            app.ColorDropDown.Value = 'Select A Color';
            app.ColorLabel.Position =[14,156,104,30];
            app.ColorDropDown.Position =[199,155,138,31];
            app.DrawButton.Position =[163,76,100,38];         
            elseif strcmp(value,'Equilateral Triangle')   
            cla(app.Axes);
            app.UpperLabel.Text='Equilateral Side Length:';
            app.UpperSliderLabel.Visible = 'on';          
            app.UpperSlider.Value = 0;
            app.UpperSlider.Visible = 'on'; 
            app.UpperLabel.Visible='on';
            app.ColorDropDown.Visible = 'on';
            app.ColorLabel.Visible = 'on';   
            app.ColorDropDown.Value ='Select A Color';            
            app.ColorLabel.Position =[14,199,104,30];
            app.ColorDropDown.Position =[200,198,138,31];
            app.DrawButton.Position=[154,151,100,38];
            app.UpperSliderLabel.Position =[146,247,43,22];
            elseif strcmp (value,'Triangle')
            cla(app.Axes);
            app.LowerLabel.Visible = 'on'; 
            app.LowerLabel.Text = 'Triangle Base:';
            app.UpperLabel.Text = 'Triangle Height:';
            app.UpperLabel.Visible = 'on';
            app.UpperSliderLabel.Text = '';
            app.UpperSlider.Value = 0; 
            app.LowerSliderLabel.Text = '';
            app.LowerSlider.Value = 0; 
            app.LowerSliderLabel.Visible = 'on';
            app.UpperSliderLabel.Visible='on';
            app.UpperSlider.Visible = 'on';
            app.LowerSlider.Visible ='on';
            app.ColorLabel.Visible ='on';
            app.ColorDropDown.Visible = 'on';
            app.ColorDropDown.Value ='Select A Color';
            app.ColorLabel.Position =[14,156,104,30];
            app.ColorDropDown.Position =[199,155,138,31];
            app.DrawButton.Position =[163,76,100,38];   
            elseif strcmp(value,'Square')
            cla(app.Axes);
            app.UpperSliderLabel.Visible ='on'; 
            app.UpperSlider.Value = 0;
            app.UpperSlider.Visible = 'on'; 
            app.UpperLabel.Visible='on';
            app.UpperLabel.Text ='Square Side Length:';
            app.ColorDropDown.Visible = 'on';
            app.ColorLabel.Visible = 'on';   
            app.ColorDropDown.Value ='Select A Color';
            app.UpperSliderLabel.Text ='';
            app.ColorLabel.Position =[14,199,104,30];
            app.ColorDropDown.Position =[200,198,138,31];
            app.DrawButton.Position=[154,151,100,38];
            app.UpperSliderLabel.Position =[146,247,43,22];
            end
        
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            cla(app.Axes);
            %setting the parameter
            selectedShape = app.ShapeDropDown.Value;
            radius = app.UpperSlider.Value;
            color = app.ColorDropDown.Value;          
            color2 = app.ColorDropDown.Value;
            if strcmp(color,'Baby Blue')
                str ='#4DBEEE';
                color = sscanf(str(2:end),'%2x%2x%2x',[1 3])/255;
            end
            if strcmp(color2,'Baby Blue')
                color2 = '#4DBEEE';
            end
            length = app.LowerSlider.Value;
            sideLength = app.UpperSlider.Value;
            width = app.UpperSlider.Value;
            base = app.UpperSlider.Value;
            height = app.LowerSlider.Value;            
            if strcmp(selectedShape, 'Circle')
            % Draw a circle with the given radius
            drawCircle(app, radius,color);
            elseif strcmp(selectedShape,'Rectangle')
            drawRectangle(app,length,width,color2);
            elseif strcmp(selectedShape,'Equilateral Triangle')
            drawEquilateralTriangle(app,sideLength,color);
            elseif strcmp(selectedShape,'Triangle')
            drawTriangle(app,base,height,color);
            elseif strcmp(selectedShape,'Square')
            drawRectangle(app,sideLength,sideLength,color)    
            end
       

        end

        % Value changed function: UpperSlider
        function UpperSliderValueChanged(app, event)
            value = app.UpperSlider.Value;
            app.UpperSliderLabel.Text = num2str(round(value,2));
            
        end

        % Value changed function: LowerSlider
        function LowerSliderValueChanged(app, event)
            value = app.LowerSlider.Value;
            app.LowerSliderLabel.Text=num2str(round(value,2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes = uiaxes(app.UIFigure);
            app.SelectyourShapeLabel = uilabel(app.UIFigure);
            app.SelectyourShapeLabel.Text = 'Select your Shape:';
            app.ShapeDropDown = uidropdown(app.UIFigure);
            app.ShapeDropDown.Items = {'Select A Shape', 'Circle', 'Rectangle', 'Equilateral Triangle', 'Triangle', 'Square'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Select A Shape';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.MyShapeDrawingAppLabel = uilabel(app.UIFigure);
            app.MyShapeDrawingAppLabel.Text = 'My Shape Drawing App';
            app.UpperLabel = uilabel(app.UIFigure);
            app.UpperLabel.Text = 'Equilateral Side Length:';
            app.LowerLabel = uilabel(app.UIFigure);
            app.LowerLabel.Text = '';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Select A Color', 'Red', 'Blue', 'Green', 'Cyan', 'Black', 'Magenta', 'Yellow', 'Baby Blue'};
            app.ColorDropDown.Visible = 'off';
            app.ColorDropDown.Placeholder = '''''';
            app.ColorDropDown.Value = 'Select A Color';
            app.UpperSlider = uislider(app.UIFigure);
            app.UpperSlider.ValueChangedFcn = createCallbackFcn(app, @UpperSliderValueChanged, true);
            app.ColorLabel = uilabel(app.UIFigure);
            app.ColorLabel.Visible = 'off';
            app.ColorLabel.Text = 'Select a Color:';
            app.UpperSliderLabel = uilabel(app.UIFigure);
            app.UpperSliderLabel.Text = '';
            app.LowerSliderLabel = uilabel(app.UIFigure);
            app.LowerSliderLabel.Text = '';
            app.MidSliderLabel = uilabel(app.UIFigure);
            app.MidSliderLabel.Text = '';
            app.LowerSlider = uislider(app.UIFigure);
            app.LowerSlider.ValueChangedFcn = createCallbackFcn(app, @LowerSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end